classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ItsEditField       matlab.ui.control.EditField
        ItsEditFieldLabel  matlab.ui.control.Label
        ClassifyButton     matlab.ui.control.Button
        PickImageButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PickImageButton
        function PickImageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*', 'Pick an Image');
            filename = strcat(pathname, filename);
            a = imread(filename);
            imshow(a, 'Parent', app.UIAxes);
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            global a;
            loadedModel = load('trained_classifier.mat');
            classifier = loadedModel.classifier;
            net = resnet50();
            imageSize = net.Layers(1).InputSize;
            featureLayer = 'fc1000';

            newImage = a;

            ds = augmentedImageDatastore(imageSize, newImage, ...
                'ColorPreprocessing', 'gray2rgb');

            imageFeatures = activations(net, ...
                ds, featureLayer, 'MiniBatchSize', 32, 'OutputAs', 'columns');

            label = predict(classifier, imageFeatures, 'ObservationsIn', 'columns');
            label = char(label);
            label = string(label);
            app.ItsEditField.Value = label;
        end

        % Value changed function: ItsEditField
        function ItsEditFieldValueChanged(app, event)
            value = app.ItsEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.PickImageButton = uibutton(app.UIFigure, 'push');
            app.PickImageButton.ButtonPushedFcn = createCallbackFcn(app, @PickImageButtonPushed, true);
            app.PickImageButton.Text = 'Pick Image';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ItsEditFieldLabel = uilabel(app.UIFigure);
            app.ItsEditFieldLabel.Text = 'It''s';
            app.ItsEditField = uieditfield(app.UIFigure, 'text');
            app.ItsEditField.ValueChangedFcn = createCallbackFcn(app, @ItsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end